classdef OFDM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OFDMsignalsimulationUIFigure  matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes3_2                     matlab.ui.control.UIAxes
        UIAxes3_3                     matlab.ui.control.UIAxes
        Button                        matlab.ui.control.Button
        DropDownLabel                 matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        EditField_2Label              matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        EditField_3Label              matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        OFDMEditFieldLabel            matlab.ui.control.Label
        OFDMEditField                 matlab.ui.control.NumericEditField
        CheckBox                      matlab.ui.control.CheckBox
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        CheckBox_2                    matlab.ui.control.CheckBox
        EditField_5                   matlab.ui.control.NumericEditField
        EditField_6                   matlab.ui.control.NumericEditField
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        OFDMCheckBox                  matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
sc = app.EditField.Value; % Размер FFT
gti = app.EditField_2.Value; % Размер защитного интервала
npbs = sc-gti; % Количество поднесущих используемых для передачи данных
omsc = app.OFDMEditField.Value; % Количество OFDM символов
cp = app.EditField_3.Value; % Циклический префикс

multithread = "";
noised = "";

bpskqpsk = app.DropDown.Value; % Выбор модуляции
channel = app.CheckBox.Value; % Вкл/Откл канал
gauss = app.CheckBox_2.Value; % Вкл/Откл шум гаусса

% Параметры канала
tau = 5;
t = (0:1:2*tau);
pdp = exp(-t/tau);
pdp = pdp/sum(pdp);
imp = sqrt(pdp).*(randn(size(pdp))+1i*randn(size(pdp)))/sqrt(2); % Импульсная характеристика

EbN0dB = [app.EditField_5.Value:app.EditField_6.Value]; % Сигнал шум соотношение
EsN0dB = EbN0dB + 10*log10(npbs/sc) + 10*log10(sc/(sc+cp));

if ~app.OFDMCheckBox.Value
    boards = length(EbN0dB)-1;
    set(app.UIAxes,'visible','on');
    set(app.UIAxes2,'visible','on');
    set(app.UIAxes3,'visible','on');
    set(app.UIAxes3_2,'visible','on');
    set(app.UIAxes3_3,'visible','on');
    set(app.UIAxes4, 'position', [636 6 464 480]);
else
    boards = length(EbN0dB);
    set(app.UIAxes,'visible','off');
    set(app.UIAxes2,'visible','off');
    set(app.UIAxes3,'visible','off');
    set(app.UIAxes3_2,'visible','off');
    set(app.UIAxes3_3,'visible','off');
    set(app.UIAxes4, 'position', [281 6 819 480]);
end

parfor ii = 1:boards

   % Transmitter
   switch bpskqpsk
    case "BPSK"
        ipBit = randn(1,npbs*omsc) > 0; % random 1's and 0's
        ipMod = 2*ipBit-1; % BPSK modulation 0 --> -1, 1 --> +1
    case "QPSK"
        ipBit = randn(1,npbs*omsc) > 0; % random 1's and 0's
        ipMod = 1i*(2*ipBit-1); % QPSK modulation 0 --> -1, 1 --> +1
   end
   
   ipMod = reshape(ipMod,npbs,omsc);

   h = zeros(sc, omsc);
   
   % ofdm building
   h((gti/2)+1:1:gti/2+npbs/2, :) = ipMod(1:1:npbs/2, :);
   h(gti/2+npbs/2+2:1:gti/2+1+npbs, :) = ipMod(npbs/2+1:1:npbs, :);
   
   xF = h.';

   xt = (sc/sqrt(npbs))*ifft(fftshift(xF.', 2));

   % cyclic prefix
   h = zeros(sc+cp, omsc);
   h(1:1:cp, :) = xt(sc-cp+1:sc, :);
   h(cp+1:1:sc+cp, :) = xt;
   
   if channel
       multithread = "multithread";
       for i=1:1:omsc
            meedle = conv(h(:, i), imp);
            h(:, i) = meedle(1:sc+cp);
       end
   end
   
   xt = h.';
   
   
   xt = reshape(xt.',1,omsc*(sc+cp));


   % Шум гаусса
   if gauss
     nt = 1/sqrt(2)*[randn(1,omsc*(sc+cp)) + j*randn(1,omsc*(sc+cp))];
   end

   % Добавляем шум к сигналу
   if gauss
    noised = "noised";
    yt = sqrt((sc+cp)/sc)*xt + 10^(-EsN0dB(ii)/20)*nt;
   else
       yt = xt;
   end

   % Приемник
   yt = reshape(yt.',(sc+cp),omsc); 
   y = zeros(sc, omsc);
   y(1:sc, :) = yt(cp+1:sc+cp, :);

   yF = (sqrt(npbs)/sc)*fftshift(fft(y).', 1); 
   
   if channel
        spectre = fft(imp, sc); % Частотный спектр канала
        yF = yF./spectre; % Эквалайзер
   end
   
   yMod = yF(:,[gti/2+[1:npbs/2] 1+gti/2+[npbs/2+1:npbs] ]); 

   % BPSK demodulation
   % +ve value --> 1, -ve value --> -1
   switch bpskqpsk
    case "BPSK"
           ipModHat = 2*floor(real(yMod/2)) + 1;
           ipModHat(find(ipModHat>1)) = +1;
           ipModHat(find(ipModHat<-1)) = -1;
 
           ipBitHat = (ipModHat+1)/2;
           ipBitHat = reshape(ipBitHat.',npbs*omsc,1).';
           nErr(ii) = size(find(int8(ipBitHat) - int8(ipBit)),2);
    case "QPSK"
           ipModHat = 2*floor(imag(yMod/2)) + 1;
           ipModHat(find(ipModHat>1)) = +1;
           ipModHat(find(ipModHat<-1)) = -1;
           
           ipBitHat = (ipModHat+1)/2;
           ipBitHat = reshape(ipBitHat.',npbs*omsc,1).';
           nErr(ii) = size(find(int8(ipBitHat) - int8(ipBit)),2);
   end
   disp("Progress: " + (ii/length(EbN0dB)));
end
%%%%START OF BLOCK FOR OFDM PLOTS%%%%%%%%
if ~app.OFDMCheckBox.Value
    disp("Calculating plot data...")
ii = length(EbN0dB);
   % Transmitter
   switch bpskqpsk
    case "BPSK"
        ipBit = randn(1,npbs*omsc) > 0; % random 1's and 0's
        ipMod = 2*ipBit-1; % BPSK modulation 0 --> -1, 1 --> +1
    case "QPSK"
        ipBit = randn(1,npbs*omsc) > 0; % random 1's and 0's
        ipMod = 1i*(2*ipBit-1); % BPSK modulation 0 --> -1, 1 --> +1
   end
   plot(app.UIAxes, abs(ipBit));
   disp("Progress 20%");
   ipMod = reshape(ipMod,npbs,omsc); % grouping into multiple symbolsa

   h = zeros(sc, omsc);

   h((gti/2)+1:1:gti/2+npbs/2, :) = ipMod(1:1:npbs/2, :);
   h(gti/2+npbs/2+2:1:gti/2+1+npbs, :) = ipMod(npbs/2+1:1:npbs, :);
   plot(app.UIAxes2, abs(h));
   disp("Progress 40%");
   xF = h.';


   % Taking FFT, the term (sc/sqrt(npbs)) is for normalizing the power of transmit symbol to 1 
   xt = (sc/sqrt(npbs))*ifft(fftshift(xF.', 2));

   % Appending cylic prefix
   h = zeros(sc+cp, omsc);
   h(1:1:cp, :) = xt(sc-cp+1:sc, :);
   h(cp+1:1:sc+cp, :) = xt;
   
   if channel
       multithread = "multithread";
       for i=1:1:omsc
            meedle = conv(h(:, i), imp);
            h(:, i) = meedle(1:sc+cp);
       end
   end
   
   xt = h.';


   % Concatenating multiple symbols to form a long vector
   xt = reshape(xt.',1,omsc*(sc+cp));
   plot(app.UIAxes3, abs(xt));
   disp("Progress 60%");


   % Gaussian noise of unit variance, 0 mean
   if gauss
     nt = 1/sqrt(2)*[randn(1,omsc*(sc+cp)) + j*randn(1,omsc*(sc+cp))];
   end

   % Adding noise, the term sqrt(80/64) is to account for the wasted energy due to cyclic prefix
   if gauss
    noised = "noised";
    yt = sqrt((sc+cp)/sc)*xt + 10^(-EsN0dB(ii)/20)*nt;
   else
       yt = xt;
   end

   % Receiver
   yt = reshape(yt.',(sc+cp),omsc); % formatting the received vector into symbols
   y = zeros(sc, omsc);
   y(1:sc, :) = yt(cp+1:sc+cp, :);

   % converting to frequency domain
   yF = (sqrt(npbs)/sc)*fftshift(fft(y).', 1); 
   
   plot(app.UIAxes3_2, abs(yF.'));
   disp("Progress 80%");
   if channel
        spectre = fft(imp, sc);
        yF = yF./spectre;
   end
   plot(app.UIAxes3_3, abs(yF.'))
   disp("Progress 100%");
   
   yMod = yF(:,[gti/2+[1:npbs/2] 1+gti/2+[npbs/2+1:npbs] ]); 

   % BPSK demodulation
   % +ve value --> 1, -ve value --> -1
   switch bpskqpsk
    case "BPSK"
           ipModHat = 2*floor(real(yMod/2)) + 1;
           ipModHat(find(ipModHat>1)) = +1;
           ipModHat(find(ipModHat<-1)) = -1;
           % converting modulated values into bits
           ipBitHat = (ipModHat+1)/2;
           ipBitHat = reshape(ipBitHat.',npbs*omsc,1).';
           nErr(ii) = size(find(int8(ipBitHat) - int8(ipBit)),2);
              if int8(ipBitHat(1:1:end)) == int8(ipBit(1:1:end))
       app.Label.Text = "TX data == RX data";
       app.Label_2.Text = "All bits equals: " + size(ipBit, 1);
   else
       app.Label.Text = "TX data ~= RX data";
       app.Label_2.Text = "Bits not equals: " + nErr(length(EbN0dB));
   end
   app.Label.Text = "TX data == RX data";
  app.Label_2.Text = "All bits equals: " + size(ipBit, 1);

    case "QPSK"
           ipModHat = 2*floor(imag(yMod/2)) + 1;
           ipModHat(find(ipModHat>1)) = +1;
           ipModHat(find(ipModHat<-1)) = -1;
           % converting modulated values into bits
           ipBitHat = (ipModHat+1)/2;
           ipBitHat = reshape(ipBitHat.',npbs*omsc,1).';
           nErr(ii) = size(find(int8(ipBitHat) - int8(ipBit)),2);
   end
 %%%%%%%%%%%%%%%%%%%%%%%%%%
disp("Wait a five seconds for plot...");
end
if bpskqpsk == "BPSK"
    mn = 1;
else
    mn = 2;
end
simBer = nErr*mn/(omsc*npbs);
theoryBer = (1/2)*erfc(sqrt(10.^(EbN0dB/10)));
theoryBer2 = erfc(sqrt(10.^(EbN0dB/10)));


cla(app.UIAxes4);
semilogy(app.UIAxes4, EbN0dB,theoryBer,'bs-','LineWidth',2);
hold(app.UIAxes4, 'on');
semilogy(app.UIAxes4, EbN0dB,theoryBer2,'r*-','LineWidth',2);
hold(app.UIAxes4, 'on');
semilogy(app.UIAxes4, EbN0dB,simBer,'mx-','LineWidth',2);
axis(app.UIAxes4, [EbN0dB(1) length(EbN0dB) 10^-5 1]);
grid(app.UIAxes4, 'on');
legend(app.UIAxes4, 'BPSK theory', 'QPSK theory', multithread + ' ' + noised +' OFDM');
xlabel(app.UIAxes4, 'Eb/No, dB')
ylabel(app.UIAxes4, 'Bit Error Rate');
if bpskqpsk == "BPSK"
    title(app.UIAxes4, 'Bit error probability curve for BPSK using OFDM');
else
    title(app.UIAxes4, 'Bit error probability curve for QPSK using OFDM');
end
disp("Complete!");
if nErr(length(EbN0dB)) == 0
    app.Label.Text = "TX data == RX data";
    app.Label_2.Text = "All bits equals: " + (npbs*omsc)*mn;
else
    app.Label.Text = "TX data ~= RX data";
    app.Label_2.Text = "Not all bits equals: " + nErr(length(EbN0dB))*omsc*npbs;
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OFDMsignalsimulationUIFigure = uifigure('Visible', 'off');
            app.OFDMsignalsimulationUIFigure.Name = 'OFDM signal simulation';
            app.UIAxes = uiaxes(app.OFDMsignalsimulationUIFigure);
            title(app.UIAxes, 'Данные')
            xlabel(app.UIAxes, 'Bit')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.OFDMsignalsimulationUIFigure);
            title(app.UIAxes2, 'OFDM mod')
            xlabel(app.UIAxes2, 'Subcarriers')
            ylabel(app.UIAxes2, 'Amplitude')
            app.UIAxes3 = uiaxes(app.OFDMsignalsimulationUIFigure);
            title(app.UIAxes3, 'OFDM временной вид')
            xlabel(app.UIAxes3, 'Subcarriers')
            ylabel(app.UIAxes3, 'Amplitude')
            app.UIAxes3_2 = uiaxes(app.OFDMsignalsimulationUIFigure);
            title(app.UIAxes3_2, 'OFDM после канала')
            xlabel(app.UIAxes3_2, 'Subcarriers')
            ylabel(app.UIAxes3_2, 'Amplitude')
            app.UIAxes3_3 = uiaxes(app.OFDMsignalsimulationUIFigure);
            title(app.UIAxes3_3, 'OFDM после эквалайзера')
            xlabel(app.UIAxes3_3, 'Subcarriers')
            ylabel(app.UIAxes3_3, 'Amplitude')
            app.Button = uibutton(app.OFDMsignalsimulationUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Запустить симуляцию';
            app.DropDownLabel = uilabel(app.OFDMsignalsimulationUIFigure);
            app.DropDownLabel.Text = 'Модуляция поднесущих';
            app.DropDown = uidropdown(app.OFDMsignalsimulationUIFigure);
            app.DropDown.Items = {'BPSK', 'QPSK'};
            app.DropDown.Value = 'BPSK';
            app.EditFieldLabel = uilabel(app.OFDMsignalsimulationUIFigure);
            app.EditFieldLabel.Text = 'Количество поднесущих';
            app.EditField = uieditfield(app.OFDMsignalsimulationUIFigure, 'numeric');
            app.EditField.Value = 128;
            app.EditField_2Label = uilabel(app.OFDMsignalsimulationUIFigure);
            app.EditField_2Label.Text = 'Защитный интервал';
            app.EditField_2 = uieditfield(app.OFDMsignalsimulationUIFigure, 'numeric');
            app.EditField_2.Value = 32;
            app.EditField_3Label = uilabel(app.OFDMsignalsimulationUIFigure);
            app.EditField_3Label.Text = 'Циклический префикс';
            app.EditField_3 = uieditfield(app.OFDMsignalsimulationUIFigure, 'numeric');
            app.EditField_3.Value = 16;
            app.OFDMEditFieldLabel = uilabel(app.OFDMsignalsimulationUIFigure);
            app.OFDMEditFieldLabel.Text = 'OFDM символы';
            app.OFDMEditField = uieditfield(app.OFDMsignalsimulationUIFigure, 'numeric');
            app.OFDMEditField.Value = 10000;
            app.CheckBox = uicheckbox(app.OFDMsignalsimulationUIFigure);
            app.CheckBox.Text = 'Многолучевое распространение';
            app.Label = uilabel(app.OFDMsignalsimulationUIFigure);
            app.Label.Text = 'Сравнение исходного и принятого сигнала';
            app.Label_2 = uilabel(app.OFDMsignalsimulationUIFigure);
            app.Label_2.Text = 'Побитное сравнение ';
            app.Label_3 = uilabel(app.OFDMsignalsimulationUIFigure);
            app.Label_3.Text = 'Результаты';
            app.UIAxes4 = uiaxes(app.OFDMsignalsimulationUIFigure);
            title(app.UIAxes4, 'Вероятность битовой ошибки')
            xlabel(app.UIAxes4, 'Сигнал/Шум, dB')
            ylabel(app.UIAxes4, 'Вероятность битовой ошибки')
            app.CheckBox_2 = uicheckbox(app.OFDMsignalsimulationUIFigure);
            app.CheckBox_2.Text = 'Гауссовский шум';
            app.EditField_5 = uieditfield(app.OFDMsignalsimulationUIFigure, 'numeric');
            app.EditField_5.Value = -10;
            app.EditField_6 = uieditfield(app.OFDMsignalsimulationUIFigure, 'numeric');
            app.EditField_6.Value = 30;
            app.Label_4 = uilabel(app.OFDMsignalsimulationUIFigure);
            app.Label_4.Text = 'Интервал Сигнал/Шум  ';
            app.Label_5 = uilabel(app.OFDMsignalsimulationUIFigure);
            app.Label_5.Text = '[';
            app.Label_6 = uilabel(app.OFDMsignalsimulationUIFigure);
            app.Label_6.Text = ':';
            app.Label_7 = uilabel(app.OFDMsignalsimulationUIFigure);
            app.Label_7.Text = ']';
            app.OFDMCheckBox = uicheckbox(app.OFDMsignalsimulationUIFigure);
            app.OFDMCheckBox.Text = 'Не показывать графики OFDM';
            % Show the figure after all components are created
            app.OFDMsignalsimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OFDM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OFDMsignalsimulationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OFDMsignalsimulationUIFigure)
        end
    end
end